classdef PanGUI_1_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PathEditFieldLabel         matlab.ui.control.Label
        PathEditField              matlab.ui.control.EditField
        LoadDenButton              matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        FrameSliderLabel           matlab.ui.control.Label
        FrameSlider                matlab.ui.control.Slider
        Image_2                    matlab.ui.control.Image
        BModeLabel                 matlab.ui.control.Label
        CEUSLabel                  matlab.ui.control.Label
        ApplyButton                matlab.ui.control.Button
        ResultsLabel               matlab.ui.control.Label
        ResultsLabel_2             matlab.ui.control.Label
        ChooseMarkersButton        matlab.ui.control.Button
        frameslimitLabel           matlab.ui.control.Label
        FrameEditFieldLabel        matlab.ui.control.Label
        FrameEditField             matlab.ui.control.NumericEditField
        secLabel                   matlab.ui.control.Label
        TGCCorrectionSwitchLabel   matlab.ui.control.Label
        TGCCorrectionSwitch        matlab.ui.control.Switch
        ExportRegButton            matlab.ui.control.Button
        Left                       matlab.ui.control.Button
        Right                      matlab.ui.control.Button
        FramesLabel                matlab.ui.control.Label
        LoadPreviousMarkersButton  matlab.ui.control.Button
        AnnulusRadiusSwitchLabel   matlab.ui.control.Label
        AnnulusRadiusSwitch        matlab.ui.control.Switch
        ResolutionCheckBox         matlab.ui.control.CheckBox
    end

    % Global parameters of "app":
    properties (Access = private)
        %Property % Description
        m_positions = 10*[1,8,6,15]+100;
        n_positions = 10*[5,2,11,7]+100;
        R = 10;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Declares global variables:
            global markers right left firstframe %shortvidout shortvidout_2 center_m center_n r
            global loadseg
            markers = 0;
            right = 0;
            left = 0;
            firstframe = 1;
            loadseg = 0;
            
            %app.UIFigure.Position = [X0,Y0,dX,dY];
            %app.UIFigure.Position = [400,150,737,614];
        end

        % Value changed function: PathEditField
        function PathEditFieldValueChanged(app, event)
            % Full path needed:
            % E:\Users\soa\Desktop\Pancreas\HUH\HUH03\Treatment period\S1\M7KD3BKM
            % E:\Users\soa\Desktop\Pancreas\Phili 017\TJU-SONO-017-F-C1\ceus clip dicom
            % [113(109-112):656(651-652)] pixels = 11 cm, [113(109-112):?(601-604)] pixels = 10 cm
            % pixel ~= 0.2 mm
            % Scale: [X,Y] = [1303:1346,111:652], Lines: [X,Y] =
            % [1337:1342(1340),125:645], Value > 100.
            % value = app.PathEditField.Value;
        end

        % Button pushed function: LoadDenButton
        function LoadDenButtonPushed(app, event)
            % Declares global variables:
            global shortvid shortvid_2 shortvidout shortvidout_2
            global name fs pixel_cm pixel_zero I L L_full LoadTime markers info firstframe
            global loadseg
            
            % Indication for user:
            app.LoadDenButton.Text = 'Loading...';
            pause(0.01);
            %F = app.LoadDenButton.Text;
            %wait(F);
            
            % Set timer:
            tic;
            
            % Changes selection:
            loadseg = 0;
            
            % Saves results folder:
            %{
            if ~exist('.\Results - temp','dir')
                mkdir('.\Results - temp');
            end
            respar = '.\Results - temp\';
            %}
            
            % User writes the path:
            locpar = app.PathEditField.Value;
            
            % Loading data:
            % [filepath,name,ext] = fileparts(filename);
            [~,name,ext] = fileparts(locpar);
            if strcmp(ext,'.dcm') || strcmp(ext,'') % DICOM file
                % Loading data:
                vid = dicomread(locpar);
                info = dicominfo(locpar);
                
                % Editing the video time range:
                vidtemp = vid(:,:,:,:); % Focused video, Phili patient
                
                % Draw data from the video:
                fs = info.NumberOfFrames/info.EffectiveDuration; % info.FrameTime
                dt = 1/fs;
                T = info.EffectiveDuration;
            else % Video file
                % Loading data:
                vid = VideoReader(locpar);
                
                % Draw data from the video:
                name = vid.Name;
                fs = vid.FrameRate;
                dt = 1/fs;
                T = vid.Duration;
                
                % Editing the video time range:
                vidtemp = read(vid); % Focused video, Phili patient
            end
            
            % Phili patient (newer data set 2):
            if contains(locpar,'HUH\HUH03\Treatment period')
                ROI_m = 123:738; % Normal
                ROI_n = 73:651; % Normal
                ROI_m_2 = 123:738; % CEUS
                ROI_n_2 = 794:1372; % CEUS
                
                % Pixel length calculation (Radial): -> Need to add.
                pixel_cm = 0;
            elseif contains(locpar,'TJU-SONO-017-F')||contains(locpar,'TJU-SONO-018-F')...
                    ||contains(locpar,'TJU-SONO-020-F')||contains(locpar,'PDAC-002-TJU')...
                    ||contains(locpar,'TJU-SONO-002-PDAC')||contains(locpar,'TJU-SONO-011-GEM')...
                    ||contains(locpar,'TJU-SONO-024-GEM')||contains(locpar,'TJU-SONO-025-F')...
                    ||contains(locpar,'TJU-SONO-021-F')||contains(locpar,'TJU-SONO-022-F')...
                    ||contains(locpar,'TJU-SONO-029-F')
                %{
                Xmid1[323:352]=337.5->337, Xleft1[68], Xright1=Xleft1+2*(Xmid1-Xleft1)+1=607, Xrightborder1[673], ...
	        Ytop[113], Ybottom[607], Xleft2=Xright1+2*(Xrightborder1-Xright1)+1=740, Xright2=Xleft2+(Xright1-Xleft1)=1279.
                    %}
                    ROI_m = 113:607; % Normal
                    ROI_n = 68:607; % Normal
                    ROI_m_2 = 113:607; % CEUS
                    ROI_n_2 = 740:1279; % CEUS
                    
                    % Resolution correction:
                    if app.ResolutionCheckBox.Value
                        ROI_m = 113:759; % Normal
                        ROI_n = 73:655; % Normal, ROI_n = 68:655;
                        ROI_m_2 = 113:759; % CEUS
                        ROI_n_2 = 788:1370; % CEUS, ROI_n_2 = 788:1375;
                    end
                    
                    % Pixel length calculation (Radial):
                    %A = double(vidtemp(125:645,1340,1,1));
                    A = double(vidtemp(50:645,1340,1,1)); % Correction for moving zero point.
                    % Resolution correction:
                    if app.ResolutionCheckBox.Value
                        A = double(vidtemp(50:797,1436,1,1)); % Correction for moving zero point.
                    end
                    A(A<100) = 0;
                    A(A>=100) = 1;
                    %B = A(2:end) - A(1:end-1);
                    %B = (B>=1).*B;
                    %[C,~] = find(B);
                    %pixel_cm = 1/mean(C(2:end)-C(1:end-1));
                    pixel_cm = info.SequenceOfUltrasoundRegions.Item_1.PhysicalDeltaX;
                    [C2,~] = find(A); % Correction for moving zero point.
                    pixel_zero = pixel_cm*(113 - (C2(1)+(50-1))); % Correction for moving zero point.
            end
            
            % Editing the video ROI and converting color into grayscale:
            L_full = size(vidtemp,4); % Maximal video limit.
            shortvid = zeros([length(ROI_m),length(ROI_n),1,L_full]);
            shortvid_2 = zeros([length(ROI_m),length(ROI_n),1,L_full]);
            for i = 1:L_full
                % Allows later editing (double instead of uint8, still [0,255] values):
                shortvid(:,:,1,i) = double(rgb2gray(vidtemp(ROI_m,ROI_n,:,i)));
                shortvid_2(:,:,1,i) = double(rgb2gray(vidtemp(ROI_m_2,ROI_n_2,:,i))); % CEUS
            end
            
            % Indication for user:
            app.LoadDenButton.Text = 'Denoising...';
            pause(0.01);
            
            % Wavelet Denoising (My):
            %[shortvidden,shortvidden_2] = PanGUI_Den(shortvid,shortvid_2);
            
            % Wavelet Denoising (Matlab):
            shortvidden = zeros(size(shortvid));
            shortvidden_2 = zeros(size(shortvid_2));
            % ('Wavelet','bior4.4','ThresholdRule','Soft','LEVEL',min([floor(log2(min([M N]))),wmaxlev([M N],wname)]))
            for i = 1:L_full % 'Level' = 2.
                img_log = log(shortvid(:,:,:,i) + 1); % [0:1:255]->[1:1:256]->[0:?:5.545...]
                img_log_2 = log(shortvid_2(:,:,:,i) + 1); % [0:1:255]->[1:1:256]->[0:?:5.545...]
                
                img_log_den = wdenoise2(img_log,2,'DenoisingMethod','UniversalThreshold');
                img_log_den_2 = wdenoise2(img_log_2,2,'DenoisingMethod','UniversalThreshold');
                
                img_log_den = exp(img_log_den) - 1; % [0:?:5.545...]->[1:1:256]->[0:1:255]
                img_log_den(img_log_den>255) = 255;
                img_log_den(img_log_den<0) = 0;
                img_log_den_2 = exp(img_log_den_2) - 1; % [0:?:5.545...]->[1:1:256]->[0:1:255]
                img_log_den_2(img_log_den_2>255) = 255;
                img_log_den_2(img_log_den_2<0) = 0;
                
                shortvidden(:,:,:,i) = img_log_den;
                shortvidden_2(:,:,:,i) = img_log_den_2;
            end
            
            % Results:
            shortvid = shortvidden;
            shortvid_2 = shortvidden_2;
            
            % Cutting the video:
            I = app.FrameEditField.Value;
            I = round(I);
            if I > L_full
                I = L_full;
            end
            L = min(L_full,I+149); % Maximal 150 frames limit.
            if L >= 150
                app.frameslimitLabel.Visible = 'on';
            else
                app.frameslimitLabel.Visible = 'off';
                I = 1;
            end
            % Intensity correction, converts to uint8[0,255]:
            shortvidout = uint8(cat(3,shortvid,cat(3,shortvid,shortvid)));
            shortvidout_2 = uint8(cat(3,shortvid_2,cat(3,shortvid_2,shortvid_2))); % CEUS
            
            % Export the video (B-Mode):
            app.Image.ImageSource = shortvidout(:,:,:,max(I,app.FrameSlider.Value));
            app.Image_2.ImageSource = shortvidout_2(:,:,:,max(I,app.FrameSlider.Value));
            app.FrameSlider.Limits = [I L];
            app.FrameSlider.MajorTicksMode = 'manual';
            app.FrameSlider.MajorTicks = round([I,min((I-rem(I,10)+10),L):10:L]);
            app.FrameSlider.MinorTicksMode = 'manual';
            app.FrameSlider.MinorTicks = round(I:1:L);
            dt = 1/info.CineRate;
            app.secLabel.Text = [num2str(round(I*dt)),'-',num2str(round(L*dt)),'[sec]'];
            app.TGCCorrectionSwitch.Value = '0';
            markers = 0;
            firstframe = I;
            
            % Set timer:
            LoadTime = toc;
            
            % Indication for user:
            app.LoadDenButton.Text = 'Load+Den.';
            pause(0.01);
        end

        % Value changed function: TGCCorrectionSwitch
        function TGCCorrectionSwitchValueChanged(app, event)
            % Declares global variables:
            global shortvid shortvid_2 shortvidout shortvidout_2 shortvidout_2_int
            global TGCTime
            
            % Notify user:
            app.TGCCorrectionSwitchLabel.Text = 'Working...';
            pause(0.01);
            
            % Set timer:
            tic;
            
            % Get parameter from user:
            value = app.TGCCorrectionSwitch.Value;
            
            % Load parameters:
            frame = app.FrameSlider.Value;
            
            % Gamma correction (TGC - Time Gain Compensation):
            if strcmp(value,'I')
                shortvidout_2_int = PanGUI_TGC(shortvid,shortvid_2,frame);
                
                % Update:
                shortvidout_2 = uint8(cat(3,shortvidout_2_int,cat(3,shortvidout_2_int,shortvidout_2_int)));
            else
                % Update:
                shortvidout_2 = uint8(cat(3,shortvid_2,cat(3,shortvid_2,shortvid_2))); % CEUS
            end
            
            % Plotting the segmented image:
            app.Image.ImageSource = shortvidout(:,:,:,frame);
            app.Image_2.ImageSource = shortvidout_2(:,:,:,frame);
            
            % Set timer:
            TGCTime = toc;
            
            % Notify user:
            app.TGCCorrectionSwitchLabel.Text = 'TGC Correction';
            pause(0.01);
        end

        % Button pushed function: ChooseMarkersButton
        function ChooseMarkersButtonPushed(app, event)
            % Declares global variables:
            global shortvidout marker_x marker_y markers area_pl % area_el
            global ax center axes angle area_el_2 area_el_3 area_el_4
            global pixel_cm
            
            % Notify user:
            app.ChooseMarkersButton.Text = 'Marking...';
            pause(0.01);
            
            img = shortvidout(:,:,:,app.FrameSlider.Value);
            
            marker_x = 0;
            marker_y = 0;
            
            % Polygon version:
            img_temp_2 = figure;
            image(img);
            title('Markers for polygon edges');
            h5 = drawpolygon;
            area_pl = createMask(h5);
            close(img_temp_2);
            
            % Ellipse Fit:
            % Binarize
            img_try = area_pl.*(area_pl == 1);
            %Igray = rgb2gray(img_try);
            Igray = img_try;
            BW = imbinarize(Igray);
            % Extract the maximum area
            BW = imclearborder(BW);
            BW = bwareafilt(BW,1);
            % Calculate centroid, orientation and major/minor axis length of the ellipse
            s = regionprops(BW,{'Centroid','Orientation','MajorAxisLength','MinorAxisLength'});
            % Calculate the ellipse line
            theta = linspace(0,2*pi);
            col = (s.MajorAxisLength/2)*cos(theta);
            row = (s.MinorAxisLength/2)*sin(theta);
            M = makehgtform('translate',[s.Centroid, 0],'zrotate',deg2rad(-1*s.Orientation));
            D = M*[col;row;zeros(1,numel(row));ones(1,numel(row))];
            % Visualize the result
            fig = figure;
            imshow(Igray)
            hold on
            plot(D(1,:),D(2,:),'r','LineWidth',2)
            ax = gca;
            %ax = load('ax.mat','ax');
            
            % Conversion:
            center = s.Centroid;
            axes = 0.5*[s.MajorAxisLength,s.MinorAxisLength];
            angle = s.Orientation;
            if angle < 0
                angle = 360 + angle;
            end
            %h1 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes,'RotationAngle',angle);
            %area_el_1 = createMask(h1);
            
            % Creating the rings:
            markers = 1;
            r = 15; % [pixels]
            r_1_cm = 0.1; % [cm]
            r_1 = round(r_1_cm/pixel_cm); % [pixels]
            r_2_cm = 0.2; % [cm]
            r_2 = round(r_2_cm/pixel_cm); % [pixels]
            r_3_cm = 0.3; % [cm]
            r_3 = round(r_3_cm/pixel_cm); % [pixels]
            r_4_cm = 0.4; % [cm]
            r_4 = round(r_4_cm/pixel_cm); % [pixels]
            r_5_cm = 0.5; % [cm]
            r_5 = round(r_5_cm/pixel_cm); % [pixels]
            r_6_cm = 0.6; % [cm]
            r_6 = round(r_6_cm/pixel_cm); % [pixels]
            r_7_cm = 0.7; % [cm]
            r_7 = round(r_7_cm/pixel_cm); % [pixels]
            r_8_cm = 0.8; % [cm]
            r_8 = round(r_8_cm/pixel_cm); % [pixels]
            r_9_cm = 0.9; % [cm]
            r_9 = round(r_9_cm/pixel_cm); % [pixels]
            r_10_cm = 1.0; % [cm]
            r_10 = round(r_10_cm/pixel_cm); % [pixels]
            if strcmp(app.AnnulusRadiusSwitch.Value,'15 Pixels') % [pixels]
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r,'RotationAngle',angle);
                area_el_2.r = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r,'RotationAngle',angle);
                area_el_3.r = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r,'RotationAngle',angle);
                area_el_4.r = createMask(h4);
            elseif strcmp(app.AnnulusRadiusSwitch.Value,'0.5 cm') % [cm]
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_1,'RotationAngle',angle);
                area_el_2.r1 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_1,'RotationAngle',angle);
                area_el_3.r1 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_1,'RotationAngle',angle);
                area_el_4.r1 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_2,'RotationAngle',angle);
                area_el_2.r2 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_2,'RotationAngle',angle);
                area_el_3.r2 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_2,'RotationAngle',angle);
                area_el_4.r2 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_3,'RotationAngle',angle);
                area_el_2.r3 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_3,'RotationAngle',angle);
                area_el_3.r3 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_3,'RotationAngle',angle);
                area_el_4.r3 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_4,'RotationAngle',angle);
                area_el_2.r4 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_4,'RotationAngle',angle);
                area_el_3.r4 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_4,'RotationAngle',angle);
                area_el_4.r4 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_5,'RotationAngle',angle);
                area_el_2.r5 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_5,'RotationAngle',angle);
                area_el_3.r5 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_5,'RotationAngle',angle);
                area_el_4.r5 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_6,'RotationAngle',angle);
                area_el_2.r6 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_6,'RotationAngle',angle);
                area_el_3.r6 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_6,'RotationAngle',angle);
                area_el_4.r6 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_7,'RotationAngle',angle);
                area_el_2.r7 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_7,'RotationAngle',angle);
                area_el_3.r7 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_7,'RotationAngle',angle);
                area_el_4.r7 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_8,'RotationAngle',angle);
                area_el_2.r8 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_8,'RotationAngle',angle);
                area_el_3.r8 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_8,'RotationAngle',angle);
                area_el_4.r8 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_9,'RotationAngle',angle);
                area_el_2.r9 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_9,'RotationAngle',angle);
                area_el_3.r9 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_9,'RotationAngle',angle);
                area_el_4.r9 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_10,'RotationAngle',angle);
                area_el_2.r10 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_10,'RotationAngle',angle);
                area_el_3.r10 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_10,'RotationAngle',angle);
                area_el_4.r10 = createMask(h4);
                
                % For plotting:
                area_el_2.r = area_el_2.r5;
                area_el_3.r = area_el_3.r5;
                area_el_4.r = area_el_4.r5;
            end
            close(fig);
            % End of Ellipse fit.
            
            % Notify user:
            app.ChooseMarkersButton.Text = 'Choose Markers';
            pause(0.01);
        end

        % Button pushed function: LoadPreviousMarkersButton
        function LoadPreviousMarkersButtonPushed(app, event)
            % Declares global variables:
            global shortvidout shortvidout_2 shortvidout_2_int
            global img_L img_L_2 markers
            global img_3 img_3_CEUS Apply2Time area_pl
            global area_el_2 area_el_3 area_el_4
            global shortvid shortvid_2 I L L_full info firstframe
            global loadseg pixel_cm
            
            % Notify user:
            app.LoadPreviousMarkersButton.Text = 'Loading...';
            pause(0.01);
            
            % Set timer:
            tic;
            
            % Changes selection:
            loadseg = 1;
            
            % Loads the segmentation:
            %uigetfile;
            [file,path] = uigetfile;
            load([path,file],'img_L');
            
            % Setting up initial parameters from user:
            gamma = app.TGCCorrectionSwitch.Value;
            frame = app.FrameSlider.Value;
            
            % Change in frame number:
            firstframe = app.FrameEditField.Value;
            if firstframe ~= I
                % Cutting the video:
                I = app.FrameEditField.Value;
                I = round(I);
                if I > L_full
                    I = L_full;
                end
                L = min(L_full,I+149); % Maximal 150 frames limit.
                if L >= 150
                    app.frameslimitLabel.Visible = 'on';
                else
                    app.frameslimitLabel.Visible = 'off';
                    I = 1;
                end
                % Intensity correction, converts to uint8[0,255]:
                shortvidout = uint8(cat(3,shortvid,cat(3,shortvid,shortvid)));
                shortvidout_2 = uint8(cat(3,shortvid_2,cat(3,shortvid_2,shortvid_2))); % CEUS
                
                % Export the video (B-Mode):
                app.Image.ImageSource = shortvidout(:,:,:,max(I,app.FrameSlider.Value));
                app.Image_2.ImageSource = shortvidout_2(:,:,:,max(I,app.FrameSlider.Value));
                app.FrameSlider.Limits = [I L];
                app.FrameSlider.MajorTicksMode = 'manual';
                app.FrameSlider.MajorTicks = round([I,min((I-rem(I,10)+10),L):10:L]);
                app.FrameSlider.MinorTicksMode = 'manual';
                app.FrameSlider.MinorTicks = round(I:1:L);
                dt = 1/info.CineRate;
                app.secLabel.Text = [num2str(round(I*dt)),'-',num2str(round(L*dt)),'[sec]'];
            end
            
            % Loads the image (B-Mode):
            %img = app.Image.ImageSource;
            img = shortvidout(:,:,:,frame);
            if strcmp(gamma,'I')
                img_CEUS = uint8(cat(3,shortvidout_2_int(:,:,:,frame),cat(3,shortvidout_2_int(:,:,:,frame),shortvidout_2_int(:,:,:,frame))));
            else
                img_CEUS = shortvidout_2(:,:,:,frame);
            end
            
            img_3 = img; % Different regions
            img_3_CEUS = img_CEUS; % Different regions
            %if markers == 1
            % Ellipse version:
            % area 1+2+3+4:
            img_3(:,:,1) = ...
                (img_L == 1).*0.5.*(255 + double(img(:,:,1))) + ...
                (img_L == 2).*0.5.*(0 + double(img(:,:,1))) + ...
                (img_L == 3).*0.5.*(0 + double(img(:,:,1))) + ...
                (img_L == 4).*0.5.*(255 + double(img(:,:,1))) + ...
                (img_L ~= 1).*(img_L ~= 2).*(img_L ~= 3).*(img_L ~= 4).*double(img(:,:,1));
            img_3(:,:,2) = ...
                (img_L == 1).*0.5.*(0 + double(img(:,:,2))) + ...
                (img_L == 2).*0.5.*(255 + double(img(:,:,2))) + ...
                (img_L == 3).*0.5.*(0 + double(img(:,:,2))) + ...
                (img_L == 4).*0.5.*(255 + double(img(:,:,2))) + ...
                (img_L ~= 1).*(img_L ~= 2).*(img_L ~= 3).*(img_L ~= 4).*double(img(:,:,2));
            img_3(:,:,3) = ...
                (img_L == 1).*0.5.*(0 + double(img(:,:,3))) + ...
                (img_L == 2).*0.5.*(0 + double(img(:,:,3))) + ...
                (img_L == 3).*0.5.*(255 + double(img(:,:,3))) + ...
                (img_L == 4).*0.5.*(0 + double(img(:,:,3))) + ...
                (img_L ~= 1).*(img_L ~= 2).*(img_L ~= 3).*(img_L ~= 4).*double(img(:,:,3));
            % area 1+2+3+4 CEUS:
            img_3_CEUS(:,:,1) = ...
                (img_L == 1).*0.5.*(255 + double(img_3_CEUS(:,:,1))) + ...
                (img_L == 2).*0.5.*(0 + double(img_3_CEUS(:,:,1))) + ...
                (img_L == 3).*0.5.*(0 + double(img_3_CEUS(:,:,1))) + ...
                (img_L == 4).*0.5.*(255 + double(img_3_CEUS(:,:,1))) + ...
                (img_L ~= 1).*(img_L ~= 2).*(img_L ~= 3).*(img_L ~= 4).*double(img_3_CEUS(:,:,1));
            img_3_CEUS(:,:,2) = ...
                (img_L == 1).*0.5.*(0 + double(img_3_CEUS(:,:,2))) + ...
                (img_L == 2).*0.5.*(255 + double(img_3_CEUS(:,:,2))) + ...
                (img_L == 3).*0.5.*(0 + double(img_3_CEUS(:,:,2))) + ...
                (img_L == 4).*0.5.*(255 + double(img_3_CEUS(:,:,2))) + ...
                (img_L ~= 1).*(img_L ~= 2).*(img_L ~= 3).*(img_L ~= 4).*double(img_3_CEUS(:,:,2));
            img_3_CEUS(:,:,3) = ...
                (img_L == 1).*0.5.*(0 + double(img_3_CEUS(:,:,3))) + ...
                (img_L == 2).*0.5.*(0 + double(img_3_CEUS(:,:,3))) + ...
                (img_L == 3).*0.5.*(255 + double(img_3_CEUS(:,:,3))) + ...
                (img_L == 4).*0.5.*(0 + double(img_3_CEUS(:,:,3))) + ...
                (img_L ~= 1).*(img_L ~= 2).*(img_L ~= 3).*(img_L ~= 4).*double(img_3_CEUS(:,:,3));
            %end
            
            % Plotting the segmented image:
            app.Image.ImageSource = img_3;
            app.Image_2.ImageSource = img_3_CEUS;
            
            % Results calculation:
            % Converting values from uint8[0,255] to double[0,1].
            img_res = 1/255*double(img(:,:,1));
            img_res_CEUS = 1/255*double(img_CEUS(:,:,1));
            res_mean1 = mean(img_res(img_L == 1));
            res_std1 = std(img_res(img_L == 1));
            res_mean1_CEUS = mean(img_res_CEUS(img_L == 1));
            res_std1_CEUS = std(img_res_CEUS(img_L == 1));
            res_mean2 = mean(img_res(img_L == 2));
            res_std2 = std(img_res(img_L == 2));
            res_mean2_CEUS = mean(img_res_CEUS(img_L == 2));
            res_std2_CEUS = std(img_res_CEUS(img_L == 2));
            res_mean3 = mean(img_res(img_L == 3));
            res_std3 = std(img_res(img_L == 3));
            res_mean3_CEUS = mean(img_res_CEUS(img_L == 3));
            res_std3_CEUS = std(img_res_CEUS(img_L == 3));
            res_mean4 = mean(img_res(img_L == 4));
            res_std4 = std(img_res(img_L == 4));
            res_mean4_CEUS = mean(img_res_CEUS(img_L == 4));
            res_std4_CEUS = std(img_res_CEUS(img_L == 4));
            % [104:495,1:540],[1:103,79:468]
            img_all = [reshape(img_res(104:495,1:540),(495-104+1)*(540-1+1),1);reshape(img_res(1:103,79:468),(103-1+1)*(468-79+1),1)];
            img_all_CEUS = [reshape(img_res_CEUS(104:495,1:540),(495-104+1)*(540-1+1),1);reshape(img_res_CEUS(1:103,79:468),(103-1+1)*(468-79+1),1)];
            res_mean_all = mean(img_all);
            res_std_all = std(img_all);
            res_mean_all_CEUS = mean(img_all_CEUS);
            res_std_all_CEUS = std(img_all_CEUS);
            app.ResultsLabel.Text = ['Results: AVG1 - ',num2str(res_mean1),', STD1 - ',num2str(res_std1),newline,...
                '              AVG2 - ',num2str(res_mean2),', STD2 - ',num2str(res_std2),newline,...
                '              AVG3 - ',num2str(res_mean3),', STD3 - ',num2str(res_std3),newline,...
                '              AVG4 - ',num2str(res_mean4),', STD4 - ',num2str(res_std4),newline,...
                '              AVG_all - ',num2str(res_mean_all),', STD_all - ',num2str(res_std_all)];
            
            app.ResultsLabel_2.Text = ['Results: AVG1 - ',num2str(res_mean1_CEUS),', STD1 - ',num2str(res_std1_CEUS),newline,...
                '              AVG2 - ',num2str(res_mean2_CEUS),', STD2 - ',num2str(res_std2_CEUS),newline,...
                '              AVG3 - ',num2str(res_mean3_CEUS),', STD3 - ',num2str(res_std3_CEUS),newline,...
                '              AVG4 - ',num2str(res_mean4_CEUS),', STD4 - ',num2str(res_std4_CEUS),newline,...
                '              AVG_all - ',num2str(res_mean_all_CEUS),', STD_all - ',num2str(res_std_all_CEUS)];
            
            % Ellipse Fit:
            % Binarize
            img_try = img_L.*(img_L == 1);
            %Igray = rgb2gray(img_try);
            Igray = img_try;
            BW = imbinarize(Igray);
            % Extract the maximum area
            BW = imclearborder(BW);
            BW = bwareafilt(BW,1);
            % Calculate centroid, orientation and major/minor axis length of the ellipse
            s = regionprops(BW,{'Centroid','Orientation','MajorAxisLength','MinorAxisLength'});
            % Calculate the ellipse line
            theta = linspace(0,2*pi);
            col = (s.MajorAxisLength/2)*cos(theta);
            row = (s.MinorAxisLength/2)*sin(theta);
            M = makehgtform('translate',[s.Centroid, 0],'zrotate',deg2rad(-1*s.Orientation));
            D = M*[col;row;zeros(1,numel(row));ones(1,numel(row))];
            % Visualize the result
            fig = figure;
            imshow(Igray)
            hold on
            plot(D(1,:),D(2,:),'r','LineWidth',2)
            ax = gca;
            %ax = load('ax.mat','ax');
            
            % Conversion:
            center = s.Centroid;
            axes = 0.5*[s.MajorAxisLength,s.MinorAxisLength];
            angle = s.Orientation;
            if angle < 0
                angle = 360 + angle;
            end
            %h1 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes,'RotationAngle',angle);
            %area_el_1 = createMask(h1);
            
            % Creating the rings:
            markers = 1;
            r = 15; % [pixels]
            r_1_cm = 0.1; % [cm]
            r_1 = round(r_1_cm/pixel_cm); % [pixels]
            r_2_cm = 0.2; % [cm]
            r_2 = round(r_2_cm/pixel_cm); % [pixels]
            r_3_cm = 0.3; % [cm]
            r_3 = round(r_3_cm/pixel_cm); % [pixels]
            r_4_cm = 0.4; % [cm]
            r_4 = round(r_4_cm/pixel_cm); % [pixels]
            r_5_cm = 0.5; % [cm]
            r_5 = round(r_5_cm/pixel_cm); % [pixels]
            r_6_cm = 0.6; % [cm]
            r_6 = round(r_6_cm/pixel_cm); % [pixels]
            r_7_cm = 0.7; % [cm]
            r_7 = round(r_7_cm/pixel_cm); % [pixels]
            r_8_cm = 0.8; % [cm]
            r_8 = round(r_8_cm/pixel_cm); % [pixels]
            r_9_cm = 0.9; % [cm]
            r_9 = round(r_9_cm/pixel_cm); % [pixels]
            r_10_cm = 1.0; % [cm]
            r_10 = round(r_10_cm/pixel_cm); % [pixels]
            if strcmp(app.AnnulusRadiusSwitch.Value,'15 Pixels') % [pixels]
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r,'RotationAngle',angle);
                area_el_2.r = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r,'RotationAngle',angle);
                area_el_3.r = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r,'RotationAngle',angle);
                area_el_4.r = createMask(h4);
            elseif strcmp(app.AnnulusRadiusSwitch.Value,'0.5 cm') % [cm]
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_1,'RotationAngle',angle);
                area_el_2.r1 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_1,'RotationAngle',angle);
                area_el_3.r1 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_1,'RotationAngle',angle);
                area_el_4.r1 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_2,'RotationAngle',angle);
                area_el_2.r2 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_2,'RotationAngle',angle);
                area_el_3.r2 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_2,'RotationAngle',angle);
                area_el_4.r2 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_3,'RotationAngle',angle);
                area_el_2.r3 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_3,'RotationAngle',angle);
                area_el_3.r3 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_3,'RotationAngle',angle);
                area_el_4.r3 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_4,'RotationAngle',angle);
                area_el_2.r4 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_4,'RotationAngle',angle);
                area_el_3.r4 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_4,'RotationAngle',angle);
                area_el_4.r4 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_5,'RotationAngle',angle);
                area_el_2.r5 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_5,'RotationAngle',angle);
                area_el_3.r5 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_5,'RotationAngle',angle);
                area_el_4.r5 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_6,'RotationAngle',angle);
                area_el_2.r6 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_6,'RotationAngle',angle);
                area_el_3.r6 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_6,'RotationAngle',angle);
                area_el_4.r6 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_7,'RotationAngle',angle);
                area_el_2.r7 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_7,'RotationAngle',angle);
                area_el_3.r7 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_7,'RotationAngle',angle);
                area_el_4.r7 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_8,'RotationAngle',angle);
                area_el_2.r8 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_8,'RotationAngle',angle);
                area_el_3.r8 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_8,'RotationAngle',angle);
                area_el_4.r8 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_9,'RotationAngle',angle);
                area_el_2.r9 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_9,'RotationAngle',angle);
                area_el_3.r9 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_9,'RotationAngle',angle);
                area_el_4.r9 = createMask(h4);
                
                h2 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+r_10,'RotationAngle',angle);
                area_el_2.r10 = createMask(h2);
                h3 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+2*r_10,'RotationAngle',angle);
                area_el_3.r10 = createMask(h3);
                h4 = images.roi.Ellipse(ax,'Center',center,'SemiAxes',axes+3*r_10,'RotationAngle',angle);
                area_el_4.r10 = createMask(h4);
                
                % For plotting:
                area_el_2.r = area_el_2.r5;
                area_el_3.r = area_el_3.r5;
                area_el_4.r = area_el_4.r5;
            end
            close(fig);
            
            % Creating the polygon:
            area_pl = (img_L == 1);
            
            % Extended ellipse version:
            img_L_2.r1 = zeros(size(img,1),size(img,2)); % Labeling regions
            img_L_2.r1(area_pl) = 1;
            img_L_2.r1(area_el_2.r1&(~area_pl)) = 2;
            img_L_2.r1(area_el_3.r1&(~area_el_2.r1)&(~area_pl)) = 3;
            img_L_2.r1(area_el_4.r1&(~area_el_3.r1)&(~area_pl)) = 4;
            
            img_L_2.r2 = zeros(size(img,1),size(img,2)); % Labeling regions
            img_L_2.r2(area_pl) = 1;
            img_L_2.r2(area_el_2.r2&(~area_pl)) = 2;
            img_L_2.r2(area_el_3.r2&(~area_el_2.r2)&(~area_pl)) = 3;
            img_L_2.r2(area_el_4.r2&(~area_el_3.r2)&(~area_pl)) = 4;
            
            img_L_2.r3 = zeros(size(img,1),size(img,2)); % Labeling regions
            img_L_2.r3(area_pl) = 1;
            img_L_2.r3(area_el_2.r3&(~area_pl)) = 2;
            img_L_2.r3(area_el_3.r3&(~area_el_2.r3)&(~area_pl)) = 3;
            img_L_2.r3(area_el_4.r3&(~area_el_3.r3)&(~area_pl)) = 4;
            
            img_L_2.r4 = zeros(size(img,1),size(img,2)); % Labeling regions
            img_L_2.r4(area_pl) = 1;
            img_L_2.r4(area_el_2.r4&(~area_pl)) = 2;
            img_L_2.r4(area_el_3.r4&(~area_el_2.r4)&(~area_pl)) = 3;
            img_L_2.r4(area_el_4.r4&(~area_el_3.r4)&(~area_pl)) = 4;
            
            img_L_2.r5 = zeros(size(img,1),size(img,2)); % Labeling regions
            img_L_2.r5(area_pl) = 1;
            img_L_2.r5(area_el_2.r5&(~area_pl)) = 2;
            img_L_2.r5(area_el_3.r5&(~area_el_2.r5)&(~area_pl)) = 3;
            img_L_2.r5(area_el_4.r5&(~area_el_3.r5)&(~area_pl)) = 4;
            
            img_L_2.r6 = zeros(size(img,1),size(img,2)); % Labeling regions
            img_L_2.r6(area_pl) = 1;
            img_L_2.r6(area_el_2.r6&(~area_pl)) = 2;
            img_L_2.r6(area_el_3.r6&(~area_el_2.r6)&(~area_pl)) = 3;
            img_L_2.r6(area_el_4.r6&(~area_el_3.r6)&(~area_pl)) = 4;
            
            img_L_2.r7 = zeros(size(img,1),size(img,2)); % Labeling regions
            img_L_2.r7(area_pl) = 1;
            img_L_2.r7(area_el_2.r7&(~area_pl)) = 2;
            img_L_2.r7(area_el_3.r7&(~area_el_2.r7)&(~area_pl)) = 3;
            img_L_2.r7(area_el_4.r7&(~area_el_3.r7)&(~area_pl)) = 4;
            
            img_L_2.r8 = zeros(size(img,1),size(img,2)); % Labeling regions
            img_L_2.r8(area_pl) = 1;
            img_L_2.r8(area_el_2.r8&(~area_pl)) = 2;
            img_L_2.r8(area_el_3.r8&(~area_el_2.r8)&(~area_pl)) = 3;
            img_L_2.r8(area_el_4.r8&(~area_el_3.r8)&(~area_pl)) = 4;
            
            img_L_2.r9 = zeros(size(img,1),size(img,2)); % Labeling regions
            img_L_2.r9(area_pl) = 1;
            img_L_2.r9(area_el_2.r9&(~area_pl)) = 2;
            img_L_2.r9(area_el_3.r9&(~area_el_2.r9)&(~area_pl)) = 3;
            img_L_2.r9(area_el_4.r9&(~area_el_3.r9)&(~area_pl)) = 4;
            
            img_L_2.r10 = zeros(size(img,1),size(img,2)); % Labeling regions
            img_L_2.r10(area_pl) = 1;
            img_L_2.r10(area_el_2.r10&(~area_pl)) = 2;
            img_L_2.r10(area_el_3.r10&(~area_el_2.r10)&(~area_pl)) = 3;
            img_L_2.r10(area_el_4.r10&(~area_el_3.r10)&(~area_pl)) = 4;
            % End of Ellipse fit.
            
            % Set timer:
            Apply2Time = toc;
            
            % Notify user:
            app.LoadPreviousMarkersButton.Text = 'Load Previous Markers';
            pause(0.01);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Declares global variables:
            global shortvidout shortvidout_2 shortvidout_2_int
            global img_L img_L_2 img_3_2 img_3_CEUS_2 markers %marker_x marker_y
            global img_3 img_3_CEUS ApplyTime area_pl %area_el img img_CEUS I L
            global area_el_2 area_el_3 area_el_4 %ax center axes angle
            global shortvid shortvid_2 I L L_full info firstframe
            global loadseg
            
            % Notify user:
            app.ApplyButton.Text = 'Analyzing...';
            pause(0.01);
            
            % Set timer:
            tic;
            
            % Setting up initial parameters from user:
            %mshift = app.PixelCorrectionEditField.Value;
            %nshift = app.PixelCorrectionEditField.Value;
            %radd = app.REditField.Value;
            %angl = app.AngleEditField.Value;
            %strch = app.StretchEditField.Value;
            gamma = app.TGCCorrectionSwitch.Value;
            frame = app.FrameSlider.Value;
            
            % Change in frame number:
            firstframe = app.FrameEditField.Value;
            if firstframe ~= I
                % Cutting the video:
                I = app.FrameEditField.Value;
                I = round(I);
                if I > L_full
                    I = L_full;
                end
                L = min(L_full,I+149); % Maximal 150 frames limit.
                if L >= 150
                    app.frameslimitLabel.Visible = 'on';
                else
                    app.frameslimitLabel.Visible = 'off';
                    I = 1;
                end
                % Intensity correction, converts to uint8[0,255]:
                shortvidout = uint8(cat(3,shortvid,cat(3,shortvid,shortvid)));
                shortvidout_2 = uint8(cat(3,shortvid_2,cat(3,shortvid_2,shortvid_2))); % CEUS
                
                % Export the video (B-Mode):
                app.Image.ImageSource = shortvidout(:,:,:,max(I,app.FrameSlider.Value));
                app.Image_2.ImageSource = shortvidout_2(:,:,:,max(I,app.FrameSlider.Value));
                app.FrameSlider.Limits = [I L];
                app.FrameSlider.MajorTicksMode = 'manual';
                app.FrameSlider.MajorTicks = round([I,min((I-rem(I,10)+10),L):10:L]);
                app.FrameSlider.MinorTicksMode = 'manual';
                app.FrameSlider.MinorTicks = round(I:1:L);
                dt = 1/info.CineRate;
                app.secLabel.Text = [num2str(round(I*dt)),'-',num2str(round(L*dt)),'[sec]'];
            end
            
            % Loads the image (B-Mode):
            %img = app.Image.ImageSource;
            img = shortvidout(:,:,:,frame);
            if strcmp(gamma,'I')
                img_CEUS = uint8(cat(3,shortvidout_2_int(:,:,:,frame),cat(3,shortvidout_2_int(:,:,:,frame),shortvidout_2_int(:,:,:,frame))));;
            else
                img_CEUS = shortvidout_2(:,:,:,frame);
            end
            
            %img_3 = img; % Different regions
            %img_3_CEUS = img_CEUS; % Different regions
            if ~loadseg % Regular analyzing
                if markers == 1
                    % Ellipse version:
                    img_L = zeros(size(img,1),size(img,2)); % Labeling regions
                    img_L(area_pl) = 1;
                    img_L(area_el_2.r&(~area_pl)) = 2;
                    img_L(area_el_3.r&(~area_el_2.r)&(~area_pl)) = 3;
                    img_L(area_el_4.r&(~area_el_3.r)&(~area_pl)) = 4;
                    % area 1+2+3+4:
                    img_3 = PanGUI_RingSegImg(img,img_L_2.r5);
                    img_3_2.r1 = PanGUI_RingSegImg(img,img_L_2.r1);
                    img_3_2.r2 = PanGUI_RingSegImg(img,img_L_2.r2);
                    img_3_2.r3 = PanGUI_RingSegImg(img,img_L_2.r3);
                    img_3_2.r4 = PanGUI_RingSegImg(img,img_L_2.r4);
                    img_3_2.r5 = PanGUI_RingSegImg(img,img_L_2.r5);
                    img_3_2.r6 = PanGUI_RingSegImg(img,img_L_2.r6);
                    img_3_2.r7 = PanGUI_RingSegImg(img,img_L_2.r7);
                    img_3_2.r8 = PanGUI_RingSegImg(img,img_L_2.r8);
                    img_3_2.r9 = PanGUI_RingSegImg(img,img_L_2.r9);
                    img_3_2.r10 = PanGUI_RingSegImg(img,img_L_2.r10);
                    % area 1+2+3+4 CEUS:
                    img_3_CEUS = PanGUI_RingSegImg(img_CEUS,img_L_2.r5);
                    img_3_CEUS_2.r1 = PanGUI_RingSegImg(img_CEUS,img_L_2.r1);
                    img_3_CEUS_2.r2 = PanGUI_RingSegImg(img_CEUS,img_L_2.r2);
                    img_3_CEUS_2.r3 = PanGUI_RingSegImg(img_CEUS,img_L_2.r3);
                    img_3_CEUS_2.r4 = PanGUI_RingSegImg(img_CEUS,img_L_2.r4);
                    img_3_CEUS_2.r5 = PanGUI_RingSegImg(img_CEUS,img_L_2.r5);
                    img_3_CEUS_2.r6 = PanGUI_RingSegImg(img_CEUS,img_L_2.r6);
                    img_3_CEUS_2.r7 = PanGUI_RingSegImg(img_CEUS,img_L_2.r7);
                    img_3_CEUS_2.r8 = PanGUI_RingSegImg(img_CEUS,img_L_2.r8);
                    img_3_CEUS_2.r9 = PanGUI_RingSegImg(img_CEUS,img_L_2.r9);
                    img_3_CEUS_2.r10 = PanGUI_RingSegImg(img_CEUS,img_L_2.r10);
                end
            else % Loading from data analyzing
                img_L = zeros(size(img,1),size(img,2)); % Labeling regions
                img_L(area_pl) = 1;
                img_L(area_el_2.r&(~area_pl)) = 2;
                img_L(area_el_3.r&(~area_el_2.r)&(~area_pl)) = 3;
                img_L(area_el_4.r&(~area_el_3.r)&(~area_pl)) = 4;
                % area 1+2+3+4:
                img_3 = PanGUI_RingSegImg(img,img_L_2.r5);
                img_3_2.r1 = PanGUI_RingSegImg(img,img_L_2.r1);
                img_3_2.r2 = PanGUI_RingSegImg(img,img_L_2.r2);
                img_3_2.r3 = PanGUI_RingSegImg(img,img_L_2.r3);
                img_3_2.r4 = PanGUI_RingSegImg(img,img_L_2.r4);
                img_3_2.r5 = PanGUI_RingSegImg(img,img_L_2.r5);
                img_3_2.r6 = PanGUI_RingSegImg(img,img_L_2.r6);
                img_3_2.r7 = PanGUI_RingSegImg(img,img_L_2.r7);
                img_3_2.r8 = PanGUI_RingSegImg(img,img_L_2.r8);
                img_3_2.r9 = PanGUI_RingSegImg(img,img_L_2.r9);
                img_3_2.r10 = PanGUI_RingSegImg(img,img_L_2.r10);
                % area 1+2+3+4 CEUS:
                img_3_CEUS = PanGUI_RingSegImg(img_CEUS,img_L_2.r5);
                img_3_CEUS_2.r1 = PanGUI_RingSegImg(img_CEUS,img_L_2.r1);
                img_3_CEUS_2.r2 = PanGUI_RingSegImg(img_CEUS,img_L_2.r2);
                img_3_CEUS_2.r3 = PanGUI_RingSegImg(img_CEUS,img_L_2.r3);
                img_3_CEUS_2.r4 = PanGUI_RingSegImg(img_CEUS,img_L_2.r4);
                img_3_CEUS_2.r5 = PanGUI_RingSegImg(img_CEUS,img_L_2.r5);
                img_3_CEUS_2.r6 = PanGUI_RingSegImg(img_CEUS,img_L_2.r6);
                img_3_CEUS_2.r7 = PanGUI_RingSegImg(img_CEUS,img_L_2.r7);
                img_3_CEUS_2.r8 = PanGUI_RingSegImg(img_CEUS,img_L_2.r8);
                img_3_CEUS_2.r9 = PanGUI_RingSegImg(img_CEUS,img_L_2.r9);
                img_3_CEUS_2.r10 = PanGUI_RingSegImg(img_CEUS,img_L_2.r10);
            end
            
            % Plotting the segmented image:
            app.Image.ImageSource = img_3;
            app.Image_2.ImageSource = img_3_CEUS;
            
            % Results calculation:
            % Converting values from uint8[0,255] to double[0,1].
            img_res = 1/255*double(img(:,:,1));
            img_res_CEUS = 1/255*double(img_CEUS(:,:,1));
            res_mean1 = mean(img_res(img_L == 1));
            res_std1 = std(img_res(img_L == 1));
            res_mean1_CEUS = mean(img_res_CEUS(img_L == 1));
            res_std1_CEUS = std(img_res_CEUS(img_L == 1));
            res_mean2 = mean(img_res(img_L == 2));
            res_std2 = std(img_res(img_L == 2));
            res_mean2_CEUS = mean(img_res_CEUS(img_L == 2));
            res_std2_CEUS = std(img_res_CEUS(img_L == 2));
            res_mean3 = mean(img_res(img_L == 3));
            res_std3 = std(img_res(img_L == 3));
            res_mean3_CEUS = mean(img_res_CEUS(img_L == 3));
            res_std3_CEUS = std(img_res_CEUS(img_L == 3));
            res_mean4 = mean(img_res(img_L == 4));
            res_std4 = std(img_res(img_L == 4));
            res_mean4_CEUS = mean(img_res_CEUS(img_L == 4));
            res_std4_CEUS = std(img_res_CEUS(img_L == 4));
            % [104:495,1:540],[1:103,79:468]
            img_all = [reshape(img_res(104:495,1:540),(495-104+1)*(540-1+1),1);reshape(img_res(1:103,79:468),(103-1+1)*(468-79+1),1)];
            img_all_CEUS = [reshape(img_res_CEUS(104:495,1:540),(495-104+1)*(540-1+1),1);reshape(img_res_CEUS(1:103,79:468),(103-1+1)*(468-79+1),1)];
            res_mean_all = mean(img_all);
            res_std_all = std(img_all);
            res_mean_all_CEUS = mean(img_all_CEUS);
            res_std_all_CEUS = std(img_all_CEUS);
            app.ResultsLabel.Text = ['Results: AVG1 - ',num2str(res_mean1),', STD1 - ',num2str(res_std1),newline,...
                '              AVG2 - ',num2str(res_mean2),', STD2 - ',num2str(res_std2),newline,...
                '              AVG3 - ',num2str(res_mean3),', STD3 - ',num2str(res_std3),newline,...
                '              AVG4 - ',num2str(res_mean4),', STD4 - ',num2str(res_std4),newline,...
                '              AVG_all - ',num2str(res_mean_all),', STD_all - ',num2str(res_std_all)];
            
            app.ResultsLabel_2.Text = ['Results: AVG1 - ',num2str(res_mean1_CEUS),', STD1 - ',num2str(res_std1_CEUS),newline,...
                '              AVG2 - ',num2str(res_mean2_CEUS),', STD2 - ',num2str(res_std2_CEUS),newline,...
                '              AVG3 - ',num2str(res_mean3_CEUS),', STD3 - ',num2str(res_std3_CEUS),newline,...
                '              AVG4 - ',num2str(res_mean4_CEUS),', STD4 - ',num2str(res_std4_CEUS),newline,...
                '              AVG_all - ',num2str(res_mean_all_CEUS),', STD_all - ',num2str(res_std_all_CEUS)];
            
            % Set timer:
            ApplyTime = toc;
            
            % Notify user:
            app.ApplyButton.Text = 'Apply';
            pause(0.01);
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            % Declares global variables:
            global shortvidout shortvidout_2 %I L
            
            % User chooses frame:
            value = round(app.FrameSlider.Value);
            app.FrameSlider.Value = value;
            app.Image.ImageSource = shortvidout(:,:,:,value);
            app.Image_2.ImageSource = shortvidout_2(:,:,:,value);
        end

        % Button pushed function: Left
        function LeftPushed(app, event)
            % Declares global variables:
            global shortvidout shortvidout_2 I %L
            
            % User chooses frame:
            value = round(app.FrameSlider.Value);
            if value > I
                app.FrameSlider.Value = value-1;
                app.Image.ImageSource = shortvidout(:,:,:,value-1);
                app.Image_2.ImageSource = shortvidout_2(:,:,:,value-1);
            end
        end

        % Button pushed function: Right
        function RightPushed(app, event)
            % Declares global variables:
            global shortvidout shortvidout_2 L %I
            
            % User chooses frame:
            value = round(app.FrameSlider.Value);
            if value < L
                app.FrameSlider.Value = value+1;
                app.Image.ImageSource = shortvidout(:,:,:,value+1);
                app.Image_2.ImageSource = shortvidout_2(:,:,:,value+1);
            end
        end

        % Button pushed function: ExportRegButton
        function ExportRegButtonPushed(app, event)
            % Declares global variables:
            global shortvidout shortvidout_2 shortvidout_2_int
            global center_m center_n r name fs img_L img_L_2 img_3_2 img_3_CEUS_2
            global pixel_cm pixel_zero markers marker_x marker_y img_3 img_3_CEUS %I L
            global LoadTime TGCTime ApplyTime ExportRegTime %ExportTime
            
            % Notify user:
            app.ExportRegButton.Text = ['Pooling',newline,'...'];
            pause(0.01);
            
            % Set timer:
            tic;
            
            % Load parameters:
            frame = app.FrameSlider.Value;
            gamma = app.TGCCorrectionSwitch.Value;
            
            % Saves results folder:
            if ~exist('.\Results - temp','dir')
                mkdir('.\Results - temp');
            end
            respar = '.\Results - temp\';
            
            % shortvidout, shortvidout_2 are uint8[0,255], shortvidout_2_int is double[0,255].
            shortvidout = double(shortvidout);
            if strcmp(gamma,'I')
                shortvidout_2 = double(uint8(cat(3,shortvidout_2_int,cat(3,shortvidout_2_int,shortvidout_2_int))));
            else
                shortvidout_2 = double(shortvidout_2);
            end
            
            % Saving the uncompressed video:
            shortvid_out = uint8(shortvidout);
            if strcmp(gamma,'I')
                shortvid_2_out = uint8(cat(3,shortvidout_2_int,cat(3,shortvidout_2_int,shortvidout_2_int)));
            else
                shortvid_2_out = uint8(shortvidout_2);
            end
            % Intensity correction:
            %shortvid_out = uint8(cat(3,shortviddec,cat(3,shortviddec,shortviddec)));
            % Saves the video:
            %v = VideoWriter([respar,'Deconvolved - ',name],'Grayscale AVI'); % Saturated video
            v = VideoWriter([respar,'Original - ',name],'Uncompressed AVI'); % Saturated video
            v.FrameRate = fs;
            open(v);
            writeVideo(v,shortvid_out);
            close(v);
            v = VideoWriter([respar,'Original_CEUS - ',name],'Uncompressed AVI'); % Saturated video
            v.FrameRate = fs;
            open(v);
            writeVideo(v,shortvid_2_out);
            close(v);
            
            % Finds cross-correlation for frame match:
            corr_perc = 0.1; % Correlation top percentage.
            corr_thr = 0.66; % Correlation match threshold.
            
            % Chronically ordered cross-correlation (double[0,255]):
            [shortvidout_pool,val2,ind2] = PanGUI_CC(shortvidout,frame,corr_perc,corr_thr);
            shortvidout_2_pool = shortvidout_2(:,:,:,val2); % Chronically ordered.
            %shortvidout_2_pool = shortvidout_2(:,:,:,ind_top(val_top>corr_thr)); % Ordered by matching.
            
            shortvidout = uint8(shortvidout);
            shortvidout_2 = uint8(shortvidout_2);
            
            % Saving the uncompressed video:
            shortvid_out = uint8(shortvidout_pool);
            shortvid_2_out = uint8(shortvidout_2_pool);
            % Intensity correction:
            %shortvid_out = uint8(cat(3,shortviddec,cat(3,shortviddec,shortviddec)));
            % Saves the video:
            %v = VideoWriter([respar,'Deconvolved - ',name],'Grayscale AVI'); % Saturated video
            v = VideoWriter([respar,'Pooled - ',name],'Uncompressed AVI'); % Saturated video
            v.FrameRate = fs;
            open(v);
            writeVideo(v,shortvid_out);
            close(v);
            v = VideoWriter([respar,'Pooled_CEUS - ',name],'Uncompressed AVI'); % Saturated video
            v.FrameRate = fs;
            open(v);
            writeVideo(v,shortvid_2_out);
            close(v);
            
            ind_reg = find(ind2==1); % Chronically ordered.
            %basic_frame = shortvidout_pool(ind2 == 1); % Chronically ordered.
            
            % MIP 1 (before registration):
            img_mip_1 = max(shortvidout_pool,[],4);
            img_mip_1_CEUS = max(shortvidout_2_pool,[],4);
            % Mean and STD 1 (before registration):
            img_avg_1 = mean(shortvidout_pool,4);
            img_avg_1_CEUS = mean(shortvidout_2_pool,4);
            img_std_1 = std(shortvidout_pool,[],4);
            img_std_1_CEUS = std(shortvidout_2_pool,[],4);
            
            % Add size to axes:
            % 2 digits after decimal point:
            %format bank
            %pixel_cm_2 = vpa(pixel_cm,2);
            %pixel_cm_2 = 0.01*round(100*pixel_cm);
            %
            N_pixel = 50;
            X = size(shortvidout_pool,2);
            Y = size(shortvidout_pool,1);
            xticklabels_0 = 0.5*pixel_cm-0.5*pixel_cm*X:pixel_cm*N_pixel:0.5*pixel_cm*X-0.5*pixel_cm;
            xticklabels = linspace(0.5*pixel_cm-0.5*pixel_cm*X,0.5*pixel_cm*X-0.5*pixel_cm,numel(xticklabels_0));
            xticklabels = 0.01*round(100*xticklabels);
            xticks = linspace(1,X,numel(xticklabels));
            yticklabels = (0:pixel_cm*N_pixel:pixel_cm*Y-pixel_cm) + pixel_zero; % Correction for moving zero point.
            yticklabels = 0.01*round(100*yticklabels);
            yticks = linspace(1,Y,numel(yticklabels));
            xangle = 45;
            % Default digits after decimal point:
            %format short
            
            % Saves results (MIP):
            f = figure('visible','off');
            image(uint8(shortvidout_pool(:,:,:,ind_reg)));
            title(['Original before registration, frame ',int2str(frame)]);
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            %{
            % Color the axes:
            ax = gca;
            ax.XColor = 'red';
            ax.YColor = 'red';
            %}
            saveas(f,[respar,'bmode_1.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(uint8(shortvidout_2_pool(:,:,:,ind_reg)));
            title(['Original (CEUS) before registration, frame ',int2str(frame)]);
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'ceus_1.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(uint8(img_mip_1));
            title('MIP before registration');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'MIP_1.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(uint8(img_mip_1_CEUS));
            title('MIP (CEUS) before registration');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'MIP_1_CEUS.jpg']);
            close(f);
            %
            % Saves results (mean and STD):
            f = figure('visible','off');
            image(uint8(img_avg_1));
            title('Mean before registration');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'AVG_1.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(uint8(img_avg_1_CEUS));
            title('Mean (CEUS) before registration');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'AVG_1_CEUS.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(1/max(img_std_1(:))*img_std_1);
            title('STD before registration - normalized');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'STD_1.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(1/max(img_std_1_CEUS(:))*img_std_1_CEUS);
            title('STD (CEUS) before registration - normalized');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'STD_1_CEUS.jpg']);
            close(f);
            
            % New length:
            L = size(shortvidout_pool,4);
            % Extension:
            description = 'pooling_0.1';
            frames_2.r1 = PanGUI_Calc(shortvidout_pool,shortvidout_2_pool,L,img_L_2.r1,description);
            description = 'pooling_0.2';
            frames_2.r2 = PanGUI_Calc(shortvidout_pool,shortvidout_2_pool,L,img_L_2.r2,description);
            description = 'pooling_0.3';
            frames_2.r3 = PanGUI_Calc(shortvidout_pool,shortvidout_2_pool,L,img_L_2.r3,description);
            description = 'pooling_0.4';
            frames_2.r4 = PanGUI_Calc(shortvidout_pool,shortvidout_2_pool,L,img_L_2.r4,description);
            description = 'pooling_0.5';
            frames_2.r5 = PanGUI_Calc(shortvidout_pool,shortvidout_2_pool,L,img_L_2.r5,description);
            description = 'pooling_0.6';
            frames_2.r6 = PanGUI_Calc(shortvidout_pool,shortvidout_2_pool,L,img_L_2.r6,description);
            description = 'pooling_0.7';
            frames_2.r7 = PanGUI_Calc(shortvidout_pool,shortvidout_2_pool,L,img_L_2.r7,description);
            description = 'pooling_0.8';
            frames_2.r8 = PanGUI_Calc(shortvidout_pool,shortvidout_2_pool,L,img_L_2.r8,description);
            description = 'pooling_0.9';
            frames_2.r9 = PanGUI_Calc(shortvidout_pool,shortvidout_2_pool,L,img_L_2.r9,description);
            description = 'pooling_1.0';
            frames_2.r10 = PanGUI_Calc(shortvidout_pool,shortvidout_2_pool,L,img_L_2.r10,description);
            % Calculating for ploting:
            description = 'pooling';
            frames = PanGUI_Calc(shortvidout_pool,shortvidout_2_pool,L,img_L,description);
            
            % Saves additional data:
            % description = 'pooling';
            save([respar,description,'_data.mat'],'xangle','xticks','xticklabels','yticks','yticklabels','-append');
            
            % Plots segmented areas:
            % B-Mode:
            % Original segmentations
            f = figure('visible','off');
            image(img_3);
            title(['Segmented B-Mode, frame ',int2str(frame)]);
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'bmode_seg.jpg']);
            close(f);
            % CEUS:
            f = figure('visible','off');
            image(img_3_CEUS);
            title(['Segmented CEUS, frame ',int2str(frame)]);
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'ceus_seg.jpg']);
            close(f);
            % New segmentations
            description_0 = '_0.1';
            PanGUI_RingsPlot(img_3_2.r1,img_3_CEUS_2.r1,frame,xangle,xticks,xticklabels,yticks,yticklabels,respar,description_0);
            description_0 = '_0.2';
            PanGUI_RingsPlot(img_3_2.r2,img_3_CEUS_2.r2,frame,xangle,xticks,xticklabels,yticks,yticklabels,respar,description_0);
            description_0 = '_0.3';
            PanGUI_RingsPlot(img_3_2.r3,img_3_CEUS_2.r3,frame,xangle,xticks,xticklabels,yticks,yticklabels,respar,description_0);
            description_0 = '_0.4';
            PanGUI_RingsPlot(img_3_2.r4,img_3_CEUS_2.r4,frame,xangle,xticks,xticklabels,yticks,yticklabels,respar,description_0);
            description_0 = '_0.5';
            PanGUI_RingsPlot(img_3_2.r5,img_3_CEUS_2.r5,frame,xangle,xticks,xticklabels,yticks,yticklabels,respar,description_0);
            description_0 = '_0.6';
            PanGUI_RingsPlot(img_3_2.r6,img_3_CEUS_2.r6,frame,xangle,xticks,xticklabels,yticks,yticklabels,respar,description_0);
            description_0 = '_0.7';
            PanGUI_RingsPlot(img_3_2.r7,img_3_CEUS_2.r7,frame,xangle,xticks,xticklabels,yticks,yticklabels,respar,description_0);
            description_0 = '_0.8';
            PanGUI_RingsPlot(img_3_2.r8,img_3_CEUS_2.r8,frame,xangle,xticks,xticklabels,yticks,yticklabels,respar,description_0);
            description_0 = '_0.9';
            PanGUI_RingsPlot(img_3_2.r9,img_3_CEUS_2.r9,frame,xangle,xticks,xticklabels,yticks,yticklabels,respar,description_0);
            description_0 = '_1.0';
            PanGUI_RingsPlot(img_3_2.r10,img_3_CEUS_2.r10,frame,xangle,xticks,xticklabels,yticks,yticklabels,respar,description_0);
            
            % Plots the results into ".\Results - temp":
            % description = 'pooling';
            description_2 = 'pooling';
            PanGUI_Plot_2(img_L,pixel_cm,L,frames,...
                shortvidout_pool,shortvidout_2_pool,ind_reg,img_3,img_3_CEUS,frame,description,description_2);
            % Extension:
            description_2 = 'pooling_0.1';
            PanGUI_Plot_2(img_L_2.r1,pixel_cm,L,frames_2.r1,...
                shortvidout_pool,shortvidout_2_pool,ind_reg,img_3_2.r1,img_3_CEUS_2.r1,frame,description,description_2);
            description_2 = 'pooling_0.2';
            PanGUI_Plot_2(img_L_2.r2,pixel_cm,L,frames_2.r2,...
                shortvidout_pool,shortvidout_2_pool,ind_reg,img_3_2.r2,img_3_CEUS_2.r2,frame,description,description_2);
            description_2 = 'pooling_0.3';
            PanGUI_Plot_2(img_L_2.r3,pixel_cm,L,frames_2.r3,...
                shortvidout_pool,shortvidout_2_pool,ind_reg,img_3_2.r3,img_3_CEUS_2.r3,frame,description,description_2);
            description_2 = 'pooling_0.4';
            PanGUI_Plot_2(img_L_2.r4,pixel_cm,L,frames_2.r4,...
                shortvidout_pool,shortvidout_2_pool,ind_reg,img_3_2.r4,img_3_CEUS_2.r4,frame,description,description_2);
            description_2 = 'pooling_0.5';
            PanGUI_Plot_2(img_L_2.r5,pixel_cm,L,frames_2.r5,...
                shortvidout_pool,shortvidout_2_pool,ind_reg,img_3_2.r5,img_3_CEUS_2.r5,frame,description,description_2);
            description_2 = 'pooling_0.6';
            PanGUI_Plot_2(img_L_2.r6,pixel_cm,L,frames_2.r6,...
                shortvidout_pool,shortvidout_2_pool,ind_reg,img_3_2.r6,img_3_CEUS_2.r6,frame,description,description_2);
            description_2 = 'pooling_0.7';
            PanGUI_Plot_2(img_L_2.r7,pixel_cm,L,frames_2.r7,...
                shortvidout_pool,shortvidout_2_pool,ind_reg,img_3_2.r7,img_3_CEUS_2.r7,frame,description,description_2);
            description_2 = 'pooling_0.8';
            PanGUI_Plot_2(img_L_2.r8,pixel_cm,L,frames_2.r8,...
                shortvidout_pool,shortvidout_2_pool,ind_reg,img_3_2.r8,img_3_CEUS_2.r8,frame,description,description_2);
            description_2 = 'pooling_0.9';
            PanGUI_Plot_2(img_L_2.r9,pixel_cm,L,frames_2.r9,...
                shortvidout_pool,shortvidout_2_pool,ind_reg,img_3_2.r9,img_3_CEUS_2.r9,frame,description,description_2);
            description_2 = 'pooling_1.0';
            PanGUI_Plot_2(img_L_2.r10,pixel_cm,L,frames_2.r10,...
                shortvidout_pool,shortvidout_2_pool,ind_reg,img_3_2.r10,img_3_CEUS_2.r10,frame,description,description_2);


            % Notify user:
            app.ExportRegButton.Text = ['Registering',newline,'...'];
            %app.ExportRegButton.FontSize = 10;
            pause(0.01);
            
            % Registration process:
            % Parameters:
            % Linear interpolation is used, as in Hanna's case.
            N = 3; % default: "N = 100;", 3 in Hanna's case.
            AFS = 2.00; % default: "AFS = 1.0;", 1.00 in Hanna's case?
            PL = 4; % default: "PL = 3;", 4 in Hanna's case.
            
            % Registration (double[0,255]):
            [shortvidreg,shortvidreg_2] = PanGUI_Reg(shortvidout_pool,shortvidout_2_pool,ind_reg,N,AFS,PL);
            
            % Saves memory space in workspace:
            clear shortvidout_pool shortvidout_2_pool
            %clear shortvidreg_neg shortvidreg_pos
            %clear shortvidreg_neg_2 shortvidreg_pos_2
            
            % Saving the uncompressed video:
            shortvid_out = uint8(shortvidreg);
            shortvid_2_out = uint8(shortvidreg_2);
            % Intensity correction:
            %shortvid_out = uint8(cat(3,shortviddec,cat(3,shortviddec,shortviddec)));
            % Saves the video:
            %v = VideoWriter([respar,'Deconvolved - ',name],'Grayscale AVI'); % Saturated video
            v = VideoWriter([respar,'Registered - ',name],'Uncompressed AVI'); % Saturated video
            v.FrameRate = fs;
            open(v);
            writeVideo(v,shortvid_out);
            close(v);
            v = VideoWriter([respar,'Registered_CEUS - ',name],'Uncompressed AVI'); % Saturated video
            v.FrameRate = fs;
            open(v);
            writeVideo(v,shortvid_2_out);
            close(v);
            
            % MIP 2 (after registration, before deconvolution):
            img_mip_2 = max(shortvidreg,[],4);
            img_mip_2_CEUS = max(shortvidreg_2,[],4);
            % Mean and STD 2 (after registration):
            img_avg_2 = mean(shortvidreg,4);
            img_avg_2_CEUS = mean(shortvidreg_2,4);
            img_std_2 = std(shortvidreg,[],4);
            img_std_2_CEUS = std(shortvidreg_2,[],4);
            
            % Saves results (MIP):
            f = figure('visible','off');
            image(uint8(shortvidreg(:,:,:,ind_reg)));
            title(['Original after registration, frame ',int2str(frame)]);
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'bmode_2.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(uint8(shortvidreg_2(:,:,:,ind_reg)));
            title(['Original (CEUS) after registration, frame ',int2str(frame)]);
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'ceus_2.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(uint8(img_mip_2));
            title('MIP after registration');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'MIP_2.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(uint8(img_mip_2_CEUS));
            title('MIP (CEUS) after registration');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'MIP_2_CEUS.jpg']);
            close(f);
            %
            % Saves results (mean and STD):
            f = figure('visible','off');
            image(uint8(img_avg_2));
            title('Mean after registration');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'AVG_2.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(uint8(img_avg_2_CEUS));
            title('Mean (CEUS) after registration');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'AVG_2_CEUS.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(1/max(img_std_2(:))*img_std_2);
            title('STD after registration - normalized');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'STD_2.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(1/max(img_std_2_CEUS(:))*img_std_2_CEUS);
            title('STD (CEUS) after registration - normalized');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'STD_2_CEUS.jpg']);
            close(f);
            
            % New length:
            L = size(shortvidreg,4);
            % Extension:
            description = 'registration_0.1';
            frames_2.r1 = PanGUI_Calc(shortvidreg,shortvidreg_2,L,img_L_2.r1,description);
            description = 'registration_0.2';
            frames_2.r2 = PanGUI_Calc(shortvidreg,shortvidreg_2,L,img_L_2.r2,description);
            description = 'registration_0.3';
            frames_2.r3 = PanGUI_Calc(shortvidreg,shortvidreg_2,L,img_L_2.r3,description);
            description = 'registration_0.4';
            frames_2.r4 = PanGUI_Calc(shortvidreg,shortvidreg_2,L,img_L_2.r4,description);
            description = 'registration_0.5';
            frames_2.r5 = PanGUI_Calc(shortvidreg,shortvidreg_2,L,img_L_2.r5,description);
            description = 'registration_0.6';
            frames_2.r6 = PanGUI_Calc(shortvidreg,shortvidreg_2,L,img_L_2.r6,description);
            description = 'registration_0.7';
            frames_2.r7 = PanGUI_Calc(shortvidreg,shortvidreg_2,L,img_L_2.r7,description);
            description = 'registration_0.8';
            frames_2.r8 = PanGUI_Calc(shortvidreg,shortvidreg_2,L,img_L_2.r8,description);
            description = 'registration_0.9';
            frames_2.r9 = PanGUI_Calc(shortvidreg,shortvidreg_2,L,img_L_2.r9,description);
            description = 'registration_1.0';
            frames_2.r10 = PanGUI_Calc(shortvidreg,shortvidreg_2,L,img_L_2.r10,description);
            % Calculating for ploting:
            description = 'registration';
            frames = PanGUI_Calc(shortvidreg,shortvidreg_2,L,img_L,description);
            
            % Saves additional data:
            % description = 'registration';
            save([respar,description,'_data.mat'],'xangle','xticks','xticklabels','yticks','yticklabels','-append');
            
            % Plots the results into ".\Results - temp":
            % description = 'registration';
            description_2 = 'registration';
            PanGUI_Plot_2(img_L,pixel_cm,L,frames,...
                shortvidreg,shortvidreg_2,ind_reg,img_3,img_3_CEUS,frame,description,description_2);
            % Extension:
            description_2 = 'registration_0.1';
            PanGUI_Plot_2(img_L_2.r1,pixel_cm,L,frames_2.r1,...
                shortvidreg,shortvidreg_2,ind_reg,img_3_2.r1,img_3_CEUS_2.r1,frame,description,description_2);
            description_2 = 'registration_0.2';
            PanGUI_Plot_2(img_L_2.r2,pixel_cm,L,frames_2.r2,...
                shortvidreg,shortvidreg_2,ind_reg,img_3_2.r2,img_3_CEUS_2.r2,frame,description,description_2);
            description_2 = 'registration_0.3';
            PanGUI_Plot_2(img_L_2.r3,pixel_cm,L,frames_2.r3,...
                shortvidreg,shortvidreg_2,ind_reg,img_3_2.r3,img_3_CEUS_2.r3,frame,description,description_2);
            description_2 = 'registration_0.4';
            PanGUI_Plot_2(img_L_2.r4,pixel_cm,L,frames_2.r4,...
                shortvidreg,shortvidreg_2,ind_reg,img_3_2.r4,img_3_CEUS_2.r4,frame,description,description_2);
            description_2 = 'registration_0.5';
            PanGUI_Plot_2(img_L_2.r5,pixel_cm,L,frames_2.r5,...
                shortvidreg,shortvidreg_2,ind_reg,img_3_2.r5,img_3_CEUS_2.r5,frame,description,description_2);
            description_2 = 'registration_0.6';
            PanGUI_Plot_2(img_L_2.r6,pixel_cm,L,frames_2.r6,...
                shortvidreg,shortvidreg_2,ind_reg,img_3_2.r6,img_3_CEUS_2.r6,frame,description,description_2);
            description_2 = 'registration_0.7';
            PanGUI_Plot_2(img_L_2.r7,pixel_cm,L,frames_2.r7,...
                shortvidreg,shortvidreg_2,ind_reg,img_3_2.r7,img_3_CEUS_2.r7,frame,description,description_2);
            description_2 = 'registration_0.8';
            PanGUI_Plot_2(img_L_2.r8,pixel_cm,L,frames_2.r8,...
                shortvidreg,shortvidreg_2,ind_reg,img_3_2.r8,img_3_CEUS_2.r8,frame,description,description_2);
            description_2 = 'registration_0.9';
            PanGUI_Plot_2(img_L_2.r9,pixel_cm,L,frames_2.r9,...
                shortvidreg,shortvidreg_2,ind_reg,img_3_2.r9,img_3_CEUS_2.r9,frame,description,description_2);
            description_2 = 'registration_1.0';
            PanGUI_Plot_2(img_L_2.r10,pixel_cm,L,frames_2.r10,...
                shortvidreg,shortvidreg_2,ind_reg,img_3_2.r10,img_3_CEUS_2.r10,frame,description,description_2);


            % Notify user:
            app.ExportRegButton.Text = ['Deconvolving',newline,'...'];
            pause(0.01);
            
            % Deconvolution:
            shortviddec = PanGUI_Dec(shortvidreg,name,fs);
            shortviddec_2 = PanGUI_Dec(shortvidreg_2,name,fs);
            
            % Saves memory space in workspace:
            clear shortvidreg shortvidreg_2
            
            % Saving the uncompressed video:
            shortvid_out = uint8(shortviddec);
            shortvid_2_out = uint8(shortviddec_2);
            % Intensity correction:
            %shortvid_out = uint8(cat(3,shortviddec,cat(3,shortviddec,shortviddec)));
            % Saves the video:
            %v = VideoWriter([respar,'Deconvolved - ',name],'Grayscale AVI'); % Saturated video
            v = VideoWriter([respar,'Deconvolved - ',name],'Uncompressed AVI'); % Saturated video
            v.FrameRate = fs;
            open(v);
            writeVideo(v,shortvid_out);
            close(v);
            v = VideoWriter([respar,'Deconvolved_CEUS - ',name],'Uncompressed AVI'); % Saturated video
            v.FrameRate = fs;
            open(v);
            writeVideo(v,shortvid_2_out);
            close(v);
            
            % MIP 3 (after deconvolution):
            img_mip_3 = max(shortviddec,[],4);
            img_mip_3_CEUS = max(shortviddec_2,[],4);
            % Mean and STD 3 (after deconvolution):
            img_avg_3 = mean(shortviddec,4);
            img_avg_3_CEUS = mean(shortviddec_2,4);
            img_std_3 = std(shortviddec,[],4);
            img_std_3_CEUS = std(shortviddec_2,[],4);
            
            % Saves results (MIP):
            f = figure('visible','off');
            image(uint8(shortviddec(:,:,:,ind_reg)));
            title(['Original after deconvolution, frame ',int2str(frame)]);
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'bmode_3.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(uint8(shortviddec_2(:,:,:,ind_reg)));
            title(['Original (CEUS) after deconvolution, frame ',int2str(frame)]);
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'ceus_3.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(uint8(img_mip_3));
            title('MIP after deconvolution');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'MIP_3.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(uint8(img_mip_3_CEUS));
            title('MIP (CEUS) after deconvolution');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'MIP_3_CEUS.jpg']);
            close(f);
            %
            % Saves results (mean and STD):
            f = figure('visible','off');
            image(uint8(img_avg_3));
            title('Mean after deconvolution');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'AVG_3.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(uint8(img_avg_3_CEUS));
            title('Mean (CEUS) after deconvolution');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'AVG_3_CEUS.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(1/max(img_std_3(:))*img_std_3);
            title('STD after deconvolution - normalized');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'STD_3.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(1/max(img_std_3_CEUS(:))*img_std_3_CEUS);
            title('STD (CEUS) after deconvolution - normalized');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'STD_3_CEUS.jpg']);
            close(f);
            
            % Calculates extra results (MIP + STD):
            img_mip_std = 1/max(img_mip_3(:))*1/max(img_std_2(:))*img_mip_3.*img_std_2;
            img_mip_std_CEUS = 1/max(img_mip_3_CEUS(:))*1/max(img_std_2_CEUS(:))*img_mip_3_CEUS.*img_std_2_CEUS;
            img_avg_mip_std_CEUS = cat(3,rgb2gray(uint8(255/max(img_mip_std_CEUS(:))*img_mip_std_CEUS)),rgb2gray(uint8(img_avg_2)),rgb2gray(uint8(img_avg_2)));
            img_avg_mip_std_CEUS_1 = cat(3,rgb2gray(uint8(255/max(img_mip_std_CEUS(:))*img_mip_std_CEUS)),rgb2gray(uint8(zeros(size(img_avg_2)))),rgb2gray(uint8(zeros(size(img_avg_2)))));
            img_avg_mip_std_CEUS_2 = cat(3,rgb2gray(uint8(zeros(size(img_avg_2)))),rgb2gray(uint8(img_avg_2)),rgb2gray(uint8(img_avg_2)));
            
            % Saves extra results (MIP + STD):
            f = figure('visible','off');
            image(img_mip_std);
            title('MIP after deconvolution + STD after registration - normalized');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'MIP_STD.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(img_mip_std_CEUS);
            title('MIP (CEUS) after deconvolution + STD (CEUS) after registration - normalized');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'MIP_STD_CEUS.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(img_avg_mip_std_CEUS);
            title('Mean a.r. combined with MIP (CEUS) a.d. + STD (CEUS) a.r. - normalized');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'AVG_MIP_STD_CEUS.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(img_avg_mip_std_CEUS_1);
            title('MIP (CEUS) a.d. + STD (CEUS) a.r. - normalized');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'AVG_MIP_STD_CEUS_1.jpg']);
            close(f);
            %
            f = figure('visible','off');
            image(img_avg_mip_std_CEUS_2);
            title('Mean a.r. - normalized');
            set(gca, 'XTick', xticks, 'XTickLabel', xticklabels);
            xtickangle(gca,xangle);
            set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
            xlabel('Lateral [cm]');
            ylabel('Axial [cm]');
            saveas(f,[respar,'AVG_MIP_STD_CEUS_2.jpg']);
            close(f);
            %{
            imwrite(img_avg_mip_std_CEUS,[respar,'AVG_MIP_STD_CEUS.gif'],'Loopcount',inf);
            imwrite(img_avg_mip_std_CEUS_1,[respar,'AVG_MIP_STD_CEUS.gif'],'WriteMode','append');
            imwrite(img_avg_mip_std_CEUS_2,[respar,'AVG_MIP_STD_CEUS.gif'],'WriteMode','append');
            %}
            
            % New length:
            L = size(shortviddec,4);
            % Extension:
            description = 'deconvolution_0.1';
            frames_2.r1 = PanGUI_Calc(shortviddec,shortviddec_2,L,img_L_2.r1,description);
            description = 'deconvolution_0.2';
            frames_2.r2 = PanGUI_Calc(shortviddec,shortviddec_2,L,img_L_2.r2,description);
            description = 'deconvolution_0.3';
            frames_2.r3 = PanGUI_Calc(shortviddec,shortviddec_2,L,img_L_2.r3,description);
            description = 'deconvolution_0.4';
            frames_2.r4 = PanGUI_Calc(shortviddec,shortviddec_2,L,img_L_2.r4,description);
            description = 'deconvolution_0.5';
            frames_2.r5 = PanGUI_Calc(shortviddec,shortviddec_2,L,img_L_2.r5,description);
            description = 'deconvolution_0.6';
            frames_2.r6 = PanGUI_Calc(shortviddec,shortviddec_2,L,img_L_2.r6,description);
            description = 'deconvolution_0.7';
            frames_2.r7 = PanGUI_Calc(shortviddec,shortviddec_2,L,img_L_2.r7,description);
            description = 'deconvolution_0.8';
            frames_2.r8 = PanGUI_Calc(shortviddec,shortviddec_2,L,img_L_2.r8,description);
            description = 'deconvolution_0.9';
            frames_2.r9 = PanGUI_Calc(shortviddec,shortviddec_2,L,img_L_2.r9,description);
            description = 'deconvolution_1.0';
            frames_2.r10 = PanGUI_Calc(shortviddec,shortviddec_2,L,img_L_2.r10,description);
            % Calculating for ploting:
            description = 'deconvolution';
            frames = PanGUI_Calc(shortviddec,shortviddec_2,L,img_L,description);
            
            % Saves additional data:
            % description = 'deconvolution';
            save([respar,description,'_data.mat'],'xangle','xticks','xticklabels','yticks','yticklabels','-append');
            
            % Plots the results into ".\Results - temp":
            % description = 'deconvolution';
            description_2 = 'deconvolution';
            PanGUI_Plot_2(img_L,pixel_cm,L,frames,...
                shortviddec,shortviddec_2,ind_reg,img_3,img_3_CEUS,frame,description,description_2);
            % Extension:
            description_2 = 'deconvolution_0.1';
            PanGUI_Plot_2(img_L_2.r1,pixel_cm,L,frames_2.r1,...
                shortviddec,shortviddec_2,ind_reg,img_3_2.r1,img_3_CEUS_2.r1,frame,description,description_2);
            description_2 = 'deconvolution_0.2';
            PanGUI_Plot_2(img_L_2.r2,pixel_cm,L,frames_2.r2,...
                shortviddec,shortviddec_2,ind_reg,img_3_2.r2,img_3_CEUS_2.r2,frame,description,description_2);
            description_2 = 'deconvolution_0.3';
            PanGUI_Plot_2(img_L_2.r3,pixel_cm,L,frames_2.r3,...
                shortviddec,shortviddec_2,ind_reg,img_3_2.r3,img_3_CEUS_2.r3,frame,description,description_2);
            description_2 = 'deconvolution_0.4';
            PanGUI_Plot_2(img_L_2.r4,pixel_cm,L,frames_2.r4,...
                shortviddec,shortviddec_2,ind_reg,img_3_2.r4,img_3_CEUS_2.r4,frame,description,description_2);
            description_2 = 'deconvolution_0.5';
            PanGUI_Plot_2(img_L_2.r5,pixel_cm,L,frames_2.r5,...
                shortviddec,shortviddec_2,ind_reg,img_3_2.r5,img_3_CEUS_2.r5,frame,description,description_2);
            description_2 = 'deconvolution_0.6';
            PanGUI_Plot_2(img_L_2.r6,pixel_cm,L,frames_2.r6,...
                shortviddec,shortviddec_2,ind_reg,img_3_2.r6,img_3_CEUS_2.r6,frame,description,description_2);
            description_2 = 'deconvolution_0.7';
            PanGUI_Plot_2(img_L_2.r7,pixel_cm,L,frames_2.r7,...
                shortviddec,shortviddec_2,ind_reg,img_3_2.r7,img_3_CEUS_2.r7,frame,description,description_2);
            description_2 = 'deconvolution_0.8';
            PanGUI_Plot_2(img_L_2.r8,pixel_cm,L,frames_2.r8,...
                shortviddec,shortviddec_2,ind_reg,img_3_2.r8,img_3_CEUS_2.r8,frame,description,description_2);
            description_2 = 'deconvolution_0.9';
            PanGUI_Plot_2(img_L_2.r9,pixel_cm,L,frames_2.r9,...
                shortviddec,shortviddec_2,ind_reg,img_3_2.r9,img_3_CEUS_2.r9,frame,description,description_2);
            description_2 = 'deconvolution_1.0';
            PanGUI_Plot_2(img_L_2.r10,pixel_cm,L,frames_2.r10,...
                shortviddec,shortviddec_2,ind_reg,img_3_2.r10,img_3_CEUS_2.r10,frame,description,description_2);


            % Notify user:
            app.ExportRegButton.Text = ['Saving',newline,'...'];
            pause(0.01);
            
            % Saves labeled data and results:
            if markers
                save([respar,name], ...
                    'center_m','center_n','r','img_L','img_L_2','frame','ind_reg','pixel_cm','marker_x','marker_y');
            else
                save([respar,name], ...
                    'center_m','center_n','r','img_L','img_L_2','frame','ind_reg','pixel_cm');
            end
            
            % Set timer:
            ExportRegTime = toc;
            
            % Saves labeled data and results:
            time = struct('Load',LoadTime,'TGC',TGCTime,'Apply',ApplyTime,'ExportReg',ExportRegTime);
            save([respar,name],'time','-append');
            
            % Notify user:
            app.ExportRegButton.Text = ['Export',newline,'+ Reg.'];
            pause(0.01);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path:';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.PathEditField.ValueChangedFcn = createCallbackFcn(app, @PathEditFieldValueChanged, true);
            app.PathEditField.Value = '.\Phili 017\TJU-SONO-017-F-C1\ceus clip dicom';
            app.LoadDenButton = uibutton(app.UIFigure, 'push');
            app.LoadDenButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDenButtonPushed, true);
            app.LoadDenButton.Text = 'Load+Den.';
            app.Image = uiimage(app.UIFigure);
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.Limits = [1 100];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Value = 1;
            app.Image_2 = uiimage(app.UIFigure);
            app.BModeLabel = uilabel(app.UIFigure);
            app.BModeLabel.Text = 'B-Mode';
            app.CEUSLabel = uilabel(app.UIFigure);
            app.CEUSLabel.Text = 'CEUS';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results:';
            app.ResultsLabel_2 = uilabel(app.UIFigure);
            app.ResultsLabel_2.Text = 'Results:';
            app.ChooseMarkersButton = uibutton(app.UIFigure, 'push');
            app.ChooseMarkersButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseMarkersButtonPushed, true);
            app.ChooseMarkersButton.Text = 'Choose Markers';
            app.frameslimitLabel = uilabel(app.UIFigure);
            app.frameslimitLabel.Visible = 'off';
            app.frameslimitLabel.Text = '(150 frames limit)';
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameEditField.Value = 1;
            app.secLabel = uilabel(app.UIFigure);
            app.secLabel.Text = '0 [sec]';
            app.TGCCorrectionSwitchLabel = uilabel(app.UIFigure);
            app.TGCCorrectionSwitchLabel.Text = 'TGC Correction';
            app.TGCCorrectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.TGCCorrectionSwitch.Items = {'0', 'I'};
            app.TGCCorrectionSwitch.ValueChangedFcn = createCallbackFcn(app, @TGCCorrectionSwitchValueChanged, true);
            app.TGCCorrectionSwitch.Value = '0';
            app.ExportRegButton = uibutton(app.UIFigure, 'push');
            app.ExportRegButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRegButtonPushed, true);
            app.ExportRegButton.Text = {'Export'; '+ Reg.'};
            app.Left = uibutton(app.UIFigure, 'push');
            app.Left.ButtonPushedFcn = createCallbackFcn(app, @LeftPushed, true);
            app.Left.Text = '<';
            app.Right = uibutton(app.UIFigure, 'push');
            app.Right.ButtonPushedFcn = createCallbackFcn(app, @RightPushed, true);
            app.Right.Text = '>';
            app.FramesLabel = uilabel(app.UIFigure);
            app.FramesLabel.Text = 'Frames';
            app.LoadPreviousMarkersButton = uibutton(app.UIFigure, 'push');
            app.LoadPreviousMarkersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPreviousMarkersButtonPushed, true);
            app.LoadPreviousMarkersButton.Text = 'Load Previous Markers';
            app.AnnulusRadiusSwitchLabel = uilabel(app.UIFigure);
            app.AnnulusRadiusSwitchLabel.Text = 'Annulus'' Radius';
            app.AnnulusRadiusSwitch = uiswitch(app.UIFigure, 'slider');
            app.AnnulusRadiusSwitch.Items = {'0.5 cm', '15 Pixels'};
            app.AnnulusRadiusSwitch.Value = '0.5 cm';
            app.ResolutionCheckBox = uicheckbox(app.UIFigure);
            app.ResolutionCheckBox.Text = 'Resolution';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PanGUI_1_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end